classdef App_test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SubmitButton                    matlab.ui.control.Button
        Panel_7                         matlab.ui.container.Panel
        ToneMappingCheckBox             matlab.ui.control.CheckBox
        ImageEnhancementPanel           matlab.ui.container.Panel
        Panel_6                         matlab.ui.container.Panel
        ButtonGroup_2                   matlab.ui.container.ButtonGroup
        SearchWindowSizeEditField       matlab.ui.control.NumericEditField
        SearchWindowSizeEditFieldLabel  matlab.ui.control.Label
        SpatialsimilarityEditField      matlab.ui.control.NumericEditField
        SpatialsimilarityEditFieldLabel  matlab.ui.control.Label
        WindowSizeEditField             matlab.ui.control.NumericEditField
        WindowSizeEditFieldLabel        matlab.ui.control.Label
        ColorsimilarityEditField        matlab.ui.control.NumericEditField
        ColorsimilarityEditFieldLabel   matlab.ui.control.Label
        DecayfactorEditField            matlab.ui.control.NumericEditField
        DecayfactorEditFieldLabel       matlab.ui.control.Label
        DiameterofpixelneighborhoodEditField  matlab.ui.control.NumericEditField
        DiameterofpixelneighborhoodEditFieldLabel  matlab.ui.control.Label
        NLFilterButton                  matlab.ui.control.RadioButton
        BilateralFilterButton           matlab.ui.control.RadioButton
        NoiseReductionCheckBox          matlab.ui.control.CheckBox
        Panel_5                         matlab.ui.container.Panel
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        ColorCorrectionMatrixCheckBox   matlab.ui.control.CheckBox
        Panel_4                         matlab.ui.container.Panel
        GammaEditField                  matlab.ui.control.NumericEditField
        GammaLabel                      matlab.ui.control.Label
        GammaCorrectionCheckBox         matlab.ui.control.CheckBox
        PreprocessingPanel              matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        AutomaticButton                 matlab.ui.control.RadioButton
        BGainEditField                  matlab.ui.control.NumericEditField
        BGainEditFieldLabel             matlab.ui.control.Label
        RGainEditField                  matlab.ui.control.NumericEditField
        RGainEditFieldLabel             matlab.ui.control.Label
        ManualButton                    matlab.ui.control.RadioButton
        WhiteBalnceCheckBox             matlab.ui.control.CheckBox
        Panel_2                         matlab.ui.container.Panel
        OffsetValueEditField            matlab.ui.control.NumericEditField
        OffsetValueEditFieldLabel       matlab.ui.control.Label
        BlackLevelCorrectionCheckBox    matlab.ui.control.CheckBox
        MetadataPanel                   matlab.ui.container.Panel
        ifmatextensionthenheightandwidtharenotrequiredLabel  matlab.ui.control.Label
        ExtensionDropDown               matlab.ui.control.DropDown
        ExtensionDropDownLabel          matlab.ui.control.Label
        BitPerSampleEditField           matlab.ui.control.NumericEditField
        BitPerSampleEditFieldLabel      matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditFieldLabel             matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        OutputDirectoryLabel            matlab.ui.control.Label
        ImportDirectoryLabel            matlab.ui.control.Label
        Export_Button                   matlab.ui.control.Button
        Import_Button                   matlab.ui.control.Button
        ImageSignalProcessingSimulatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property 
        inputFolder = 'None'% Description
        outputFolder = 'None'
        black_lvl = 0;
        White_Balance_condition = 0;
        White_Balance_type = 1;
        GammaCorrectioncondition = 1;
        ColorCorrectioncondition = 0;
        NoiseReductiontype = 0;
        NoiseReductioncondition = 0;
       % height = evalin('base', 'height');
       % width = evalin('base', 'width');
       % bitsPerSample = evalin('base', 'bitsPerSample');
       % blackLevel = evalin('base', 'blackLevel');
       %  redGain = evalin('base', 'redGain');
       %  blueGain = evalin('base', 'blueGain');

         height;
         width;
         bitsPerSample=12;
         blackLevel=0;

         redGain = 1;
         blueGain = 1;
         gamma = 1;
         extension = '.mat';

         d;
         sigmaColor;
         sigmaSpace;

         h;
         windowSize;
         searchWindowSize;
         
         CCM = [
         1.0, 0.0, 0.0;
         0.0, 1.0, 0.0;
         0.0, 0.0, 1.0
         ];

         tone_map_condition = 0;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
         app.height = app.HeightEditField.Value;
         app.width = app.WidthEditField.Value;
         app.bitsPerSample= app.BitPerSampleEditField.Value;
         app.blackLevel = app.OffsetValueEditField.Value;
         app.gamma = app.GammaEditField.Value;
         app.extension = app.ExtensionDropDown.Value; 
         app.redGain = app.RGainEditField.Value;
         app.blueGain = app.BGainEditField.Value;
         app.black_lvl = app.BlackLevelCorrectionCheckBox.Value;
         app.White_Balance_condition = app.WhiteBalnceCheckBox.Value;
         app.GammaCorrectioncondition = app.GammaCorrectionCheckBox.Value;
         app.White_Balance_type = app.ButtonGroup.SelectedObject.Text;
         app.ColorCorrectioncondition = app.ColorCorrectionMatrixCheckBox.Value;
         app.NoiseReductioncondition = app.NoiseReductionCheckBox.Value;
         app.NoiseReductiontype = app.ButtonGroup_2.SelectedObject.Text;
         app.d = app.DiameterofpixelneighborhoodEditField.Value;
         app.sigmaColor = app.ColorsimilarityEditField.Value;
         app.sigmaSpace = app.SpatialsimilarityEditField.Value;
         app.h = app.DecayfactorEditField.Value;
         app.windowSize = app.WindowSizeEditField.Value;
         app.searchWindowSize = app.SearchWindowSizeEditField.Value;
         app.CCM(1,1) = app.EditField.Value;
         app.CCM(1,2) = app.EditField_6.Value;
         app.CCM(1,3) = app.EditField_9.Value;
         app.CCM(2,1) = app.EditField_4.Value;
         app.CCM(2,2) = app.EditField_7.Value;
         app.CCM(2,3) = app.EditField_10.Value;
         app.CCM(3,1) = app.EditField_5.Value;
         app.CCM(3,2) = app.EditField_8.Value;
         app.CCM(3,3) = app.EditField_11.Value;
         app.tone_map_condition = app.ToneMappingCheckBox.Value;

        end

        % Button pushed function: Import_Button
        function Import_ButtonPushed(app, event)
            app.inputFolder = uigetdir;
            disp(['Input Folder: ' app.inputFolder]);
        end

        % Button pushed function: Export_Button
        function Export_ButtonPushed(app, event)
            app.outputFolder = uigetdir;
            disp(['Output Folder: ' app.outputFolder]);
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)

% File search pattern
fileSearchPattern = ['*' app.extension];

% Use dir with fullfile to list files with the specified extension
rawImageFiles = dir(fullfile(app.inputFolder, fileSearchPattern));

% Loop through the files and process each one

for i = 1:numel(rawImageFiles)
    try


        switch app.extension
            case '.mat'
                % Get the current file name
                fileName = rawImageFiles(i).name;
                data = load(fullfile(app.inputFolder, fileName));
                % Access the image data using the correct field name
                correct_variable_name = 'img'; % Replace with the correct variable name
                rawData = data.(correct_variable_name);

            case '.raw'
                % Get the current file name
                fileName = rawImageFiles(i).name;

                % Open the raw image file for reading
                fileID = fopen(fullfile(app.inputFolder, fileName), 'rb');

                % Check if the file was opened successfully
                if fileID == -1
                    error(['Error opening file: ' fileName]);
                end

                % Read the raw data
                rawData = fread(fileID, [app.width, app.height], 'uint16');

                % Adjust data type and dimensions as needed
                rawData = bitand(rawData, (2^app.bitsPerSample) - 1);

                % Close the file
                fclose(fileID);
        end
        outputFileName = [fileName, '_rgbOG.png'];
        imwrite(rawData, fullfile(app.outputFolder, outputFileName));

        % BLOCK: 1 Perform Black Level Correction
        if app.black_lvl == 1
            processedData = black_level_func(rawData, app.bitsPerSample, app.blackLevel);
            %disp('--Black Level Correction');
            outputFileName = [fileName, '_rgbblack_lvl.png'];
            imwrite(processedData, fullfile(app.outputFolder, outputFileName));
        end

        % BLOCK: 2 Perform demosaicking (Bayer to RGB conversion)
        processedData = demosaic_lvl(processedData, app.bitsPerSample); % Adjust the pattern if it's different
        %disp('--Demosaicked');
        outputFileName = [fileName, '_rgbDE.png'];
        imwrite(processedData, fullfile(app.outputFolder, outputFileName));

        % BLOCK: 3 White Balance
        if app.White_Balance_condition == 1
            switch app.White_Balance_type
                case 'Manual'
                    processedData = manual_White_balance(processedData, app.redGain, app.blueGain, app.bitsPerSample);
                    %disp('--Manual White Balance Applied');
                    outputFileName = [fileName, '_rgbWB.png'];
                    imwrite(processedData, fullfile(app.outputFolder, outputFileName));
                case 'Automatic'
                    processedData = Automatic_white_balance(processedData, app.bitsPerSample);
                    %disp('--Automatic White Balance Applied');
            end
        end

        % BLOCK: 4 Gamma Correction
        if app.GammaCorrectioncondition == 1
            processedData = Gamma_correction(processedData, app.gamma, app.bitsPerSample);
            %disp('--Gamma Correction');
        end

        % BLOCK: 5 Color Correction Matrix
        if app.ColorCorrectioncondition == 1
            processedData = color_correction_matrix(processedData,app.CCM);
            %disp('--Color Correction');
        end

        % BLOCK: 6 Noise Reduction
        if app.NoiseReductioncondition == 1
            switch app.NoiseReductiontype
                case 'Bilateral Filter'
                    processedData = Bilateral_Filter(processedData, app.d, app.sigmaColor, app.sigmaSpace);
                    outputFileName = [fileName, '_rgbNoise_reduction.png'];
                    imwrite(processedData, fullfile(app.outputFolder, outputFileName));
                    %disp('--Bilateral Filter Applied');
                case 'NL Filter'
                    processedData = NI_Filter(processedData, app.h, app.windowSize, app.searchWindowSize);
                    %disp('--NL Filter Applied');
            end
        end

        % BLOCK: 7 Tone Mappping
        if app.tone_map_condition == 1
            processedData = tone_mapping(processedData);
        end
        disp(['Processed ' num2str(i) ' out of ' num2str(numel(rawImageFiles)) ' images.']);
        outputFileName = [fileName, '_rgb.png'];
        imwrite(processedData, fullfile(app.outputFolder, outputFileName));
        
    catch E
        % Log the error message along with the file name to a CSV file
        errorLog = fopen('error_data1.csv', 'a');
        fprintf(errorLog, 'File Name,Error Message\n');
        fprintf(errorLog, '%s,%s\n', fileName, E.message);
        fclose(errorLog);
    end
   
end
 disp('Processing complete!');
        end

        % Value changed function: BlackLevelCorrectionCheckBox
        function BlackLevelCorrectionCheckBoxValueChanged(app, event)
            app.black_lvl = app.BlackLevelCorrectionCheckBox.Value;
         
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
          app.White_Balance_type = app.ButtonGroup.SelectedObject.Text;
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.width = app.WidthEditField.Value;
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.height = app.HeightEditField.Value;

        end

        % Value changed function: BitPerSampleEditField
        function BitPerSampleEditFieldValueChanged(app, event)
            app.bitsPerSample = app.BitPerSampleEditField.Value;
        end

        % Value changed function: OffsetValueEditField
        function OffsetValueEditFieldValueChanged(app, event)
            app.blackLevel = app.OffsetValueEditField.Value;
        end

        % Value changed function: ExtensionDropDown
        function ExtensionDropDownValueChanged(app, event)
            value = app.ExtensionDropDown.Value;
            switch value
                case '.raw'
                    app.extension = value;
                case '.mat'
                    app.extension = value;
            end
        end

        % Value changed function: GammaCorrectionCheckBox
        function GammaCorrectionCheckBoxValueChanged(app, event)
            app.GammaCorrectioncondition = app.GammaCorrectionCheckBox.Value;
        end

        % Value changed function: WhiteBalnceCheckBox
        function WhiteBalnceCheckBoxValueChanged(app, event)
            app.White_Balance_condition = app.WhiteBalnceCheckBox.Value;
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            app.gamma = app.GammaEditField.Value;
        end

        % Value changed function: RGainEditField
        function RGainEditFieldValueChanged(app, event)
            app.redGain = app.RGainEditField.Value;
            
        end

        % Value changed function: BGainEditField
        function BGainEditFieldValueChanged(app, event)
            app.blueGain = app.BGainEditField.Value;
            
        end

        % Value changed function: ColorCorrectionMatrixCheckBox
        function ColorCorrectionMatrixCheckBoxValueChanged(app, event)
            app.ColorCorrectioncondition = app.ColorCorrectionMatrixCheckBox.Value;
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            app.NoiseReductiontype = app.ButtonGroup_2.SelectedObject.Text;
        end

        % Value changed function: NoiseReductionCheckBox
        function NoiseReductionCheckBoxValueChanged(app, event)
            app.NoiseReductioncondition = app.NoiseReductionCheckBox.Value;
        end

        % Value changed function: DiameterofpixelneighborhoodEditField
        function DiameterofpixelneighborhoodEditFieldValueChanged(app, event)
            app.d = app.DiameterofpixelneighborhoodEditField.Value;
        end

        % Value changed function: ColorsimilarityEditField
        function ColorsimilarityEditFieldValueChanged(app, event)
            app.sigmaColor = app.ColorsimilarityEditField.Value;
        end

        % Value changed function: SpatialsimilarityEditField
        function SpatialsimilarityEditFieldValueChanged(app, event)
            app.sigmaSpace = app.SpatialsimilarityEditField.Value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.CCM(1,1) = app.EditField.Value;

        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            app.CCM(1,2) = app.EditField_6.Value;
            
        end

        % Value changed function: EditField_9
        function EditField_9ValueChanged(app, event)
            app.CCM(1,3) = app.EditField_9.Value;
            
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            app.CCM(2,1) = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_7
        function EditField_7ValueChanged(app, event)
            app.CCM(2,2) = app.EditField_7.Value;
            
        end

        % Value changed function: EditField_10
        function EditField_10ValueChanged(app, event)
            app.CCM(2,3) = app.EditField_10.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            app.CCM(3,1) = app.EditField_5.Value;
            
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            app.CCM(3,2) = app.EditField_8.Value;
            
        end

        % Value changed function: EditField_11
        function EditField_11ValueChanged(app, event)
            app.CCM(3,3) = app.EditField_11.Value;
            
        end

        % Value changed function: DecayfactorEditField
        function DecayfactorEditFieldValueChanged(app, event)
            app.h = app.DecayfactorEditField.Value;
            
        end

        % Value changed function: WindowSizeEditField
        function WindowSizeEditFieldValueChanged(app, event)
            app.windowSize = app.WindowSizeEditField.Value;
            
        end

        % Value changed function: SearchWindowSizeEditField
        function SearchWindowSizeEditFieldValueChanged(app, event)
            app.searchWindowSize = app.SearchWindowSizeEditField.Value;
            
        end

        % Value changed function: ToneMappingCheckBox
        function ToneMappingCheckBoxValueChanged(app, event)
            app.tone_map_condition = app.ToneMappingCheckBox.Value;
            
        end

        % Callback function
        function EndButtonPushed(app, event)
            app.exit_flag = -1; 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageSignalProcessingSimulatorLabel = uilabel(app.UIFigure);
            app.ImageSignalProcessingSimulatorLabel.Text = 'Image Signal Processing Simulator';
            app.Panel = uipanel(app.UIFigure);
            app.Import_Button = uibutton(app.Panel, 'push');
            app.Import_Button.ButtonPushedFcn = createCallbackFcn(app, @Import_ButtonPushed, true);
            app.Import_Button.Icon = fullfile(pathToMLAPP, 'filedrop.png');
            app.Import_Button.Text = '';
            app.Export_Button = uibutton(app.Panel, 'push');
            app.Export_Button.ButtonPushedFcn = createCallbackFcn(app, @Export_ButtonPushed, true);
            app.Export_Button.Icon = fullfile(pathToMLAPP, 'new1.png');
            app.Export_Button.Text = '';
            app.ImportDirectoryLabel = uilabel(app.Panel);
            app.ImportDirectoryLabel.Text = 'Import Directory';
            app.OutputDirectoryLabel = uilabel(app.Panel);
            app.OutputDirectoryLabel.Text = 'Output Directory';
            app.MetadataPanel = uipanel(app.UIFigure);
            app.MetadataPanel.Title = 'Metadata';
            app.WidthEditFieldLabel = uilabel(app.MetadataPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.MetadataPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Value = 1920;
            app.HeightEditFieldLabel = uilabel(app.MetadataPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.MetadataPanel, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Value = 1080;
            app.BitPerSampleEditFieldLabel = uilabel(app.MetadataPanel);
            app.BitPerSampleEditFieldLabel.Text = 'Bit Per Sample';
            app.BitPerSampleEditField = uieditfield(app.MetadataPanel, 'numeric');
            app.BitPerSampleEditField.ValueChangedFcn = createCallbackFcn(app, @BitPerSampleEditFieldValueChanged, true);
            app.BitPerSampleEditField.Value = 12;
            app.ExtensionDropDownLabel = uilabel(app.MetadataPanel);
            app.ExtensionDropDownLabel.Text = 'Extension';
            app.ExtensionDropDown = uidropdown(app.MetadataPanel);
            app.ExtensionDropDown.Items = {'.raw', '.mat'};
            app.ExtensionDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtensionDropDownValueChanged, true);
            app.ExtensionDropDown.Value = '.raw';
            app.ifmatextensionthenheightandwidtharenotrequiredLabel = uilabel(app.MetadataPanel);
            app.ifmatextensionthenheightandwidtharenotrequiredLabel.Text = '(*if .mat extension then height and width are not required)';
            app.PreprocessingPanel = uipanel(app.UIFigure);
            app.PreprocessingPanel.Title = 'Pre-processing';
            app.Panel_2 = uipanel(app.PreprocessingPanel);
            app.BlackLevelCorrectionCheckBox = uicheckbox(app.Panel_2);
            app.BlackLevelCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BlackLevelCorrectionCheckBoxValueChanged, true);
            app.BlackLevelCorrectionCheckBox.Text = 'Black Level Correction';
            app.OffsetValueEditFieldLabel = uilabel(app.Panel_2);
            app.OffsetValueEditFieldLabel.Text = 'Offset Value';
            app.OffsetValueEditField = uieditfield(app.Panel_2, 'numeric');
            app.OffsetValueEditField.ValueChangedFcn = createCallbackFcn(app, @OffsetValueEditFieldValueChanged, true);
            app.OffsetValueEditField.Value = 64;
            app.Panel_3 = uipanel(app.PreprocessingPanel);
            app.WhiteBalnceCheckBox = uicheckbox(app.Panel_3);
            app.WhiteBalnceCheckBox.ValueChangedFcn = createCallbackFcn(app, @WhiteBalnceCheckBoxValueChanged, true);
            app.WhiteBalnceCheckBox.Text = ' White Balnce';
            app.ButtonGroup = uibuttongroup(app.Panel_3);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ManualButton = uiradiobutton(app.ButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.ManualButton.Value = true;
            app.RGainEditFieldLabel = uilabel(app.ButtonGroup);
            app.RGainEditFieldLabel.Text = 'R Gain';
            app.RGainEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.RGainEditField.ValueChangedFcn = createCallbackFcn(app, @RGainEditFieldValueChanged, true);
            app.RGainEditField.Value = 1.67;
            app.BGainEditFieldLabel = uilabel(app.ButtonGroup);
            app.BGainEditFieldLabel.Text = 'B Gain';
            app.BGainEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.BGainEditField.ValueChangedFcn = createCallbackFcn(app, @BGainEditFieldValueChanged, true);
            app.BGainEditField.Value = 2.5;
            app.AutomaticButton = uiradiobutton(app.ButtonGroup);
            app.AutomaticButton.Text = 'Automatic';
            app.ImageEnhancementPanel = uipanel(app.UIFigure);
            app.ImageEnhancementPanel.Title = 'Image Enhancement ';
            app.Panel_4 = uipanel(app.ImageEnhancementPanel);
            app.GammaCorrectionCheckBox = uicheckbox(app.Panel_4);
            app.GammaCorrectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @GammaCorrectionCheckBoxValueChanged, true);
            app.GammaCorrectionCheckBox.Text = 'Gamma Correction';
            app.GammaLabel = uilabel(app.Panel_4);
            app.GammaLabel.Text = 'Gamma (γ)';
            app.GammaEditField = uieditfield(app.Panel_4, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.GammaEditField.Value = 0.3;
            app.Panel_5 = uipanel(app.ImageEnhancementPanel);
            app.ColorCorrectionMatrixCheckBox = uicheckbox(app.Panel_5);
            app.ColorCorrectionMatrixCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorCorrectionMatrixCheckBoxValueChanged, true);
            app.ColorCorrectionMatrixCheckBox.Text = 'Color Correction Matrix';
            app.EditField = uieditfield(app.Panel_5, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 1;
            app.EditField_4 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.EditField_7 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_7.ValueChangedFcn = createCallbackFcn(app, @EditField_7ValueChanged, true);
            app.EditField_7.Value = 1;
            app.EditField_8 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            app.EditField_9 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_9.ValueChangedFcn = createCallbackFcn(app, @EditField_9ValueChanged, true);
            app.EditField_10 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_10.ValueChangedFcn = createCallbackFcn(app, @EditField_10ValueChanged, true);
            app.EditField_11 = uieditfield(app.Panel_5, 'numeric');
            app.EditField_11.ValueChangedFcn = createCallbackFcn(app, @EditField_11ValueChanged, true);
            app.EditField_11.Value = 1;
            app.Panel_6 = uipanel(app.ImageEnhancementPanel);
            app.NoiseReductionCheckBox = uicheckbox(app.Panel_6);
            app.NoiseReductionCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoiseReductionCheckBoxValueChanged, true);
            app.NoiseReductionCheckBox.Text = 'Noise Reduction';
            app.ButtonGroup_2 = uibuttongroup(app.Panel_6);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.BilateralFilterButton = uiradiobutton(app.ButtonGroup_2);
            app.BilateralFilterButton.Text = 'Bilateral Filter';
            app.BilateralFilterButton.Value = true;
            app.NLFilterButton = uiradiobutton(app.ButtonGroup_2);
            app.NLFilterButton.Text = 'NL Filter';
            app.DiameterofpixelneighborhoodEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.DiameterofpixelneighborhoodEditFieldLabel.Text = 'Diameter of pixel neighborhood';
            app.DiameterofpixelneighborhoodEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.DiameterofpixelneighborhoodEditField.ValueChangedFcn = createCallbackFcn(app, @DiameterofpixelneighborhoodEditFieldValueChanged, true);
            app.DiameterofpixelneighborhoodEditField.Value = 1;
            app.DecayfactorEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.DecayfactorEditFieldLabel.Text = 'Decay factor';
            app.DecayfactorEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.DecayfactorEditField.ValueChangedFcn = createCallbackFcn(app, @DecayfactorEditFieldValueChanged, true);
            app.DecayfactorEditField.Value = 0.2;
            app.ColorsimilarityEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.ColorsimilarityEditFieldLabel.Text = 'Color similarity';
            app.ColorsimilarityEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.ColorsimilarityEditField.ValueChangedFcn = createCallbackFcn(app, @ColorsimilarityEditFieldValueChanged, true);
            app.ColorsimilarityEditField.Value = 0.15;
            app.WindowSizeEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.WindowSizeEditFieldLabel.Text = 'Window Size';
            app.WindowSizeEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.WindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @WindowSizeEditFieldValueChanged, true);
            app.WindowSizeEditField.Value = 5;
            app.SpatialsimilarityEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.SpatialsimilarityEditFieldLabel.Text = 'Spatial similarity';
            app.SpatialsimilarityEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.SpatialsimilarityEditField.ValueChangedFcn = createCallbackFcn(app, @SpatialsimilarityEditFieldValueChanged, true);
            app.SpatialsimilarityEditField.Value = 80;
            app.SearchWindowSizeEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.SearchWindowSizeEditFieldLabel.Text = 'Search Window Size';
            app.SearchWindowSizeEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.SearchWindowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SearchWindowSizeEditFieldValueChanged, true);
            app.SearchWindowSizeEditField.Value = 3;
            app.Panel_7 = uipanel(app.UIFigure);
            app.ToneMappingCheckBox = uicheckbox(app.Panel_7);
            app.ToneMappingCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToneMappingCheckBoxValueChanged, true);
            app.ToneMappingCheckBox.Text = 'Tone Mapping';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_test1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end